classdef window1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        button_save     matlab.ui.control.Button
        Label           matlab.ui.control.Label
        spinner_alpha   matlab.ui.control.Spinner
        spinner_beta    matlab.ui.control.Spinner
        Label_2         matlab.ui.control.Label
        spinner_gamma   matlab.ui.control.Spinner
        Label_3         matlab.ui.control.Label
        spinner_delta   matlab.ui.control.Spinner
        Label_4         matlab.ui.control.Label
        spinner_lambda  matlab.ui.control.Spinner
        Label_5         matlab.ui.control.Label
        spinner_mu      matlab.ui.control.Spinner
        Label_6         matlab.ui.control.Label
        button_load     matlab.ui.control.Button
        spinner_p1      matlab.ui.control.Spinner
        pLabel          matlab.ui.control.Label
        spinner_p2      matlab.ui.control.Spinner
        p2Label         matlab.ui.control.Label
        spinner_T1      matlab.ui.control.Spinner
        T1Label         matlab.ui.control.Label
        spinner_T2      matlab.ui.control.Spinner
        T2Label         matlab.ui.control.Label
        spinner_Y1      matlab.ui.control.Spinner
        Y1Label         matlab.ui.control.Label
        spinner_Y2      matlab.ui.control.Spinner
        Y2Label         matlab.ui.control.Label
        spinner_Y3      matlab.ui.control.Spinner
        Y3Label         matlab.ui.control.Label
        button_run      matlab.ui.control.Button
        ButtonGroup     matlab.ui.container.ButtonGroup
        u2              matlab.ui.control.RadioButton
        u3              matlab.ui.control.RadioButton
        u4              matlab.ui.control.RadioButton
        u0              matlab.ui.control.RadioButton
        spinner_t0      matlab.ui.control.Spinner
        spinner_dt      matlab.ui.control.Spinner
        spinner_t1      matlab.ui.control.Spinner
        Label_7         matlab.ui.control.Label
        spinner_Y2trg   matlab.ui.control.Spinner
        Y2Label_2       matlab.ui.control.Label
        spinner_n       matlab.ui.control.Spinner
        nLabel          matlab.ui.control.Label
        spinner_k       matlab.ui.control.Spinner
        kLabel          matlab.ui.control.Label
        spinner_Z       matlab.ui.control.Spinner
        ZLabel          matlab.ui.control.Label
        spinner_00      matlab.ui.control.Spinner
        Label_8         matlab.ui.control.Label
        Label_9         matlab.ui.control.Label
        DropDown        matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        fig1=NaN; 
        fig2=NaN;
        fig3=NaN;
        fig4=NaN;
        sfunc=NaN;
        data=0;
    end
       
    methods (Access = private)
        function setCoefs(app, path)
            try
                coeffs =readmatrix(path);
                app.spinner_alpha.Value=coeffs(1); 
                app.spinner_beta.Value=coeffs(2);
                app.spinner_gamma.Value=coeffs(3);
                app.spinner_delta.Value=coeffs(4);
                app.spinner_lambda.Value=coeffs(5);
                app.spinner_mu.Value=coeffs(6);
                app.spinner_p1.Value=coeffs(7);
                app.spinner_p2.Value=coeffs(8);
                app.spinner_T1.Value=coeffs(9);
                app.spinner_T2.Value=coeffs(10);
                app.spinner_Y1.Value=coeffs(11);
                app.spinner_Y2.Value=coeffs(12);
                app.spinner_Y3.Value=coeffs(13);
            catch
                msgbox("Указан неверный путь","Ошибка чтения файла");
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setCoefs("F:\labs\8sem\Диплом\prog\attr.txt");
           
        end

        % Button pushed function: button_save
        function button_savePushed(app, event)
            coeffs = [app.spinner_alpha.Value, app.spinner_beta.Value,...
                app.spinner_gamma.Value,app.spinner_delta.Value,...
                app.spinner_lambda.Value,app.spinner_mu.Value,...
                app.spinner_p1.Value,app.spinner_p2.Value,...
                app.spinner_T1.Value,app.spinner_T2.Value,...
                app.spinner_Y1.Value,app.spinner_Y2.Value,...
                app.spinner_Y3.Value
                ];
            
            [file,path] = uiputfile({'All Files (*.*)'});
            try 
                writematrix(coeffs,strcat(path,file));
            catch 
                msgbox("Указан неверный путь","Ошибка записи файла");
            end
           
            
        end

        % Button pushed function: button_load
        function button_loadButtonPushed(app, event)
            
            
            [file,path] = uigetfile;
            app.setCoefs(strcat(path,file));
            
                
        end

        % Button pushed function: button_run
        function button_runButtonPushed(app, event)


                coeffs = [app.spinner_alpha.Value, app.spinner_beta.Value,...
                app.spinner_gamma.Value,app.spinner_delta.Value,...
                app.spinner_lambda.Value,app.spinner_mu.Value,...
                app.spinner_p1.Value,app.spinner_p2.Value,...
                app.spinner_T1.Value,app.spinner_T2.Value,...
                app.spinner_Y1.Value,app.spinner_Y2.Value,...
                app.spinner_Y3.Value,app.spinner_Y2trg.Value,...
                app.spinner_n.Value,app.spinner_k.Value,...
                app.spinner_Z.Value
                ];
                
                if(app.spinner_t0.Value<app.spinner_dt.Value && app.spinner_dt.Value<app.spinner_t1.Value)
                    
                [Ys,p]=pFromUi(coeffs);
                    
                t0=app.spinner_t0.Value;
                dt=app.spinner_dt.Value;
                t1=app.spinner_t1.Value;
            
                
                try
                    app.UIFigure.Visible ='off';
                    
                    if(app.u2.Value)
                        fun=cell(4,1);
                        fun{1}= @control2;
                        fun{2}= @control0;
                        fun{3}= @control2_s;
                        fun{4}= @control0_s;
                        
                        res=cell(3,1);
                        res{1}=euler_ui(fun{1},t0,dt,t1,Ys,p);
                        res{2}=euler_ui(fun{2},t0,dt,t1,Ys,p);
                        res{3}=euler_ui(fun{3},t0,dt,t1,Ys,p,app.DropDown.Value);
                        res{4}=euler_ui(fun{4},t0,dt,t1,Ys,p,[app.DropDown.Value;'1']);
                        
                        if(ishandle(app.fig1))
                            close(app.fig1);                    
                        end
                        if(ishandle(app.fig2))
                            close(app.fig2);
                        end
                        if(ishandle(app.fig4))
                            close(app.fig4);                    
                        end
                        if(ishandle(app.fig3))
                            close(app.fig3);
                        end
                                              
                        [app.fig1,app.fig2]=plotter(res(1:2),1,"Система управления без возмущений",t0:dt:t1+dt);       
                        [app.fig3,app.fig4]=plotter_s(res(3:4),1,"Система управления с возмущениями",t0:dt:t1+dt);
                        

                    end

                    if(app.u3.Value) 
                         
                        fun=cell(3,1);
                        fun{1}= @control3;
                        fun{2}= @control0;
                        fun{3}= @control3_s;
                        fun{4}= @control0_s;
                        
                        res=cell(4,1);
                        res{1}=euler_ui(fun{1},t0,dt,t1,Ys,p);
                        res{2}=euler_ui(fun{2},t0,dt,t1,Ys,p);
                        res{3}=euler_ui(fun{3},t0,dt,t1,Ys,p,app.DropDown.Value);
                        res{4}=euler_ui(fun{4},t0,dt,t1,Ys,p,[app.DropDown.Value;'2']);
                        
                        if(ishandle(app.fig1))
                            close(app.fig1);                    
                        end
                        if(ishandle(app.fig2))
                            close(app.fig2);
                        end
                        if(ishandle(app.fig4))
                            close(app.fig4);                    
                        end
                        if(ishandle(app.fig3))
                            close(app.fig3);
                        end
                      
                        
                        [app.fig1,app.fig2]=plotter(res(1:2),1,"Система управления без возмущений",t0:dt:t1+dt);
                        [app.fig3,app.fig4]=plotter_s(res(3:4),1,"Система управления с возмущениями",t0:dt:t1+dt);

                    end
                    
                    if(app.u4.Value) 
                    
                    
                     fun=cell(3,1);
                        fun{1}= @control4;
                        fun{2}= @control0;
                        fun{3}= @control4_s;
                        fun{4}= @control0_s;
                        
                        res=cell(3,1);
                        res{1}=euler_ui(fun{1},t0,dt,t1,Ys,p);
                        res{2}=euler_ui(fun{2},t0,dt,t1,Ys,p);
                        res{3}=euler_ui(fun{3},t0,dt,t1,Ys,p,app.DropDown.Value);
                        res{4}=euler_ui(fun{4},t0,dt,t1,Ys,p,[app.DropDown.Value;'1']);
                        
                        if(ishandle(app.fig1))
                            close(app.fig1);                    
                        end
                        if(ishandle(app.fig2))
                            close(app.fig2);
                        end
                        if(ishandle(app.fig4))
                            close(app.fig4);                    
                        end
                        if(ishandle(app.fig3))
                            close(app.fig3);
                        end
                      
                        
                        [app.fig1,app.fig2]=plotter(res(1:2),1,"Система управления без возмущений",t0:dt:t1+dt);
                        
                        
                        [app.fig3,app.fig4]=plotter_s(res(3:4),1,"Система управления с возмущениями",t0:dt:t1+dt);
                        

                    end
                    
                    if(app.u0.Value) 
                         fun = @control0_s; 
                         res=euler_ui(fun,t0,dt,t1,Ys,p,['3';'1']);
                    if(ishandle(app.fig1))
                            close(app.fig1);                    
                    end
                    if(ishandle(app.fig2))
                            close(app.fig2);
                    end

                    app.fig1= plotter0(res,1,"fP",t0:dt:t1+dt);
                    app.fig2=figure;
                    plot3(res(:,1),res(:,2),res(:,3),'k');
                    xlabel('Y1');
                    ylabel('Y2');
                    zlabel('Y3');
                    
                    figure
                    hold on
                    plot(t0:dt:t1+dt,res(:,1));
                    plot(t0:dt:t1+dt,res(:,2));
                    plot(t0:dt:t1+dt,res(:,3));
                    hold off

                    hold off
                    title('u1 plot')   
                    end
                    
                    app.UIFigure.Visible ='on';
                   
                catch 
                    disp('error');
                    app.UIFigure.Visible ='on';
                end
                app.UIFigure.Visible = "On";


                else
                msgbox("Некорректный интервал","Ошибка");
                end
                
       
        end

        % Callback function
        function button_clear_figButtonPushed(app, event)


        end

        % Callback function
        function button_searchButtonPushed(app, event)
%              coeffs = [app.spinner_alpha.Value, app.spinner_beta.Value,...
%                 app.spinner_gamma.Value,app.spinner_delta.Value,...
%                 app.spinner_lambda.Value,app.spinner_mu.Value,...
%                 app.spinner_p1.Value,app.spinner_p2.Value,...
%                 app.spinner_T1.Value,app.spinner_T2.Value,...
%                 app.spinner_Y1.Value,app.spinner_Y2.Value,...
%                 app.spinner_Y3.Value,app.spinner_Y2trg.Value,...
%                 app.spinner_n.Value,app.spinner_k.Value,...
%                 app.spinner_Z.Value
%                 ];
%                 
%                 if(app.spinner_t0.Value<app.spinner_dt.Value && app.spinner_dt.Value<app.spinner_t1.Value)
%                     
%                 [Ys,p]=pFromUi(coeffs);
%                     
%                 t0=app.spinner_t0.Value;
%                 dt=app.spinner_dt.Value;
%                 t1=app.spinner_t1.Value;
%                 
%                 switch 
%                 
%                 try
%                     app.UIFigure.Visible ='off';
%                         search()       
%                             
% 
%                     app.UIFigure.Visible ='on';
%                 catch 
%                     disp('error');
%                     app.UIFigure.Visible ='on';
%                 end
%                 
%                 else
%                 msgbox("Некорректный интервал","Ошибка");
%                 end
%                 
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case '1'
                    app.ZLabel.Text = 'Z';
                    app.spinner_Z.Visible='on';
                    app.T1Label.Text = 'T';
                    
                    app.T2Label.Visible = 'off';
                    
                                      
                case '2' 
                    app.ZLabel.Text = 'A';
                    app.ZLabel.Visible = 'on';
                    app.spinner_Z.Visible='on';
                    app.T1Label.Text = 'T';
                    
                    app.T2Label.Visible = 'off';
                    
                    app.sfunc = @(t)sin(t);
                    
                case '3'
                    app.spinner_Z.Visible='off';
                    app.ZLabel.Visible = 'off';
                    app.T1Label.Text = 'T1';
                    app.spinner_T2.Visible = 'on';
                    app.T2Label.Visible = 'on';
                    
            end
        end

        % Size changed function: ButtonGroup
        function ButtonGroupSizeChanged(app, event)
            position = app.ButtonGroup.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.button_save = uibutton(app.UIFigure, 'push');
            app.button_save.ButtonPushedFcn = createCallbackFcn(app, @button_savePushed, true);
            app.button_save.Text = 'Сохранить';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'α';
            app.spinner_alpha = uispinner(app.UIFigure);
            app.spinner_alpha.Step = 0.01;
            app.spinner_beta = uispinner(app.UIFigure);
            app.spinner_beta.Step = 0.01;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'β';
            app.spinner_gamma = uispinner(app.UIFigure);
            app.spinner_gamma.Step = 0.01;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'γ';
            app.spinner_delta = uispinner(app.UIFigure);
            app.spinner_delta.Step = 0.01;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'δ';
            app.spinner_lambda = uispinner(app.UIFigure);
            app.spinner_lambda.Step = 0.01;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'λ';
            app.spinner_mu = uispinner(app.UIFigure);
            app.spinner_mu.Step = 0.01;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'µ';
            app.button_load = uibutton(app.UIFigure, 'push');
            app.button_load.ButtonPushedFcn = createCallbackFcn(app, @button_loadButtonPushed, true);
            app.button_load.Text = 'Загрузить';
            app.spinner_p1 = uispinner(app.UIFigure);
            app.spinner_p1.Step = 0.01;
            app.pLabel = uilabel(app.UIFigure);
            app.pLabel.Text = 'p';
            app.spinner_p2 = uispinner(app.UIFigure);
            app.spinner_p2.Step = 0.01;
            app.spinner_p2.Visible = 'off';
            app.p2Label = uilabel(app.UIFigure);
            app.p2Label.Visible = 'off';
            app.p2Label.Text = 'p2';
            app.spinner_T1 = uispinner(app.UIFigure);
            app.spinner_T1.Step = 0.01;
            app.T1Label = uilabel(app.UIFigure);
            app.T1Label.Text = 'T1';
            app.spinner_T2 = uispinner(app.UIFigure);
            app.spinner_T2.Step = 0.01;
            app.T2Label = uilabel(app.UIFigure);
            app.T2Label.Text = 'T2';
            app.spinner_Y1 = uispinner(app.UIFigure);
            app.spinner_Y1.Step = 0.01;
            app.Y1Label = uilabel(app.UIFigure);
            app.Y1Label.Text = 'Y1';
            app.spinner_Y2 = uispinner(app.UIFigure);
            app.spinner_Y2.Step = 0.01;
            app.Y2Label = uilabel(app.UIFigure);
            app.Y2Label.Text = 'Y2';
            app.spinner_Y3 = uispinner(app.UIFigure);
            app.spinner_Y3.Step = 0.01;
            app.Y3Label = uilabel(app.UIFigure);
            app.Y3Label.Text = 'Y3';
            app.button_run = uibutton(app.UIFigure, 'push');
            app.button_run.ButtonPushedFcn = createCallbackFcn(app, @button_runButtonPushed, true);
            app.button_run.Text = 'Моделировать';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Системы управления';
            app.ButtonGroup.SizeChangedFcn = createCallbackFcn(app, @ButtonGroupSizeChanged, true);
            app.u2 = uiradiobutton(app.ButtonGroup);
            app.u2.Text = 'Система управления 1';
            app.u2.Value = true;
            app.u3 = uiradiobutton(app.ButtonGroup);
            app.u3.Text = 'Система управления 2';
            app.u4 = uiradiobutton(app.ButtonGroup);
            app.u4.Text = 'Система управления 3';
            app.u0 = uiradiobutton(app.ButtonGroup);
            app.u0.Text = 'Система без управления';
            app.spinner_t0 = uispinner(app.UIFigure);
            app.spinner_t0.Step = 0.01;
            app.spinner_t0.Limits = [0 Inf];
            app.spinner_dt = uispinner(app.UIFigure);
            app.spinner_dt.Step = 0.01;
            app.spinner_dt.Limits = [0 Inf];
            app.spinner_dt.Value = 0.01;
            app.spinner_t1 = uispinner(app.UIFigure);
            app.spinner_t1.Step = 0.01;
            app.spinner_t1.Limits = [0 Inf];
            app.spinner_t1.Value = 15;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Интервал моделирования';
            app.spinner_Y2trg = uispinner(app.UIFigure);
            app.spinner_Y2trg.Step = 0.01;
            app.Y2Label_2 = uilabel(app.UIFigure);
            app.Y2Label_2.Text = 'Y2*';
            app.spinner_n = uispinner(app.UIFigure);
            app.spinner_n.Step = 0.01;
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n';
            app.spinner_k = uispinner(app.UIFigure);
            app.spinner_k.Step = 0.01;
            app.kLabel = uilabel(app.UIFigure);
            app.kLabel.Text = 'k';
            app.spinner_Z = uispinner(app.UIFigure);
            app.spinner_Z.Step = 0.01;
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Tag = 'label_z';
            app.ZLabel.Text = 'Z';
            app.spinner_00 = uispinner(app.UIFigure);
            app.spinner_00.Step = 0.01;
            app.spinner_00.Visible = 'off';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Visible = 'off';
            app.Label_8.Text = '00';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'Тип возмущиений';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'const', 'функция', 'случайные'};
            app.DropDown.ItemsData = {'1', '2', '3'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = window1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end